classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        EncodeTab                  matlab.ui.container.Tab
        SourceSelectButtonGroup_2  matlab.ui.container.ButtonGroup
        charsField                 matlab.ui.control.EditField
        EditFieldLabel             matlab.ui.control.Label
        CustomCharachtersButton    matlab.ui.control.RadioButton
        AllCharachtersButton_2     matlab.ui.control.RadioButton
        OutputLabel                matlab.ui.control.Label
        RunButton                  matlab.ui.control.Button
        modeSelect                 matlab.ui.control.DropDown
        CompressionAlgorithmDropDownLabel  matlab.ui.control.Label
        input                      matlab.ui.control.EditField
        InputEditFieldLabel        matlab.ui.control.Label
        DecodeTab                  matlab.ui.container.Tab
        decodeGrp                  matlab.ui.container.ButtonGroup
        charsDeco                  matlab.ui.control.EditField
        EditFieldLabel_2           matlab.ui.control.Label
        CustomCharachtersButton_2  matlab.ui.control.RadioButton
        AllCharachtersButton_3     matlab.ui.control.RadioButton
        RunButtonDeco              matlab.ui.control.Button
        modeSelectDeco             matlab.ui.control.DropDown
        CompressionAlgorithmDropDownLabel_2  matlab.ui.control.Label
        inputDeco                  matlab.ui.control.EditField
        InputEditFieldLabel_2      matlab.ui.control.Label
    end


    properties (Access = private)
        charList = 'abcdefghijklmnopqrstuvwxyz,? ';
        % The full alphabet (probably). With the special chars and
        % everything. Added "period" to the text for debugging purposes.

        usedSrcEnco = ""; % The source which we will be using.
        usedSrcDeco = ""; % For decoding too.
    end

    methods (Access = private)

        function [coded, compRatio]= lempelZivEnco(app, src, charSrc)
            % Get the unique sequences:
            seqs = getUniqueSeq(app, src);

            % Get the Index/suffix pairs
            pairs = getIndexSuffixPair(app, seqs, charSrc);

            % Append both parts of the pair
            shiftFactor = 2^ceil(log2(numel(charSrc)));
            pairAppend =  shiftFactor * pairs(:,1) + pairs(:,2);

            % Get the number of bits required for each pair
            maxIndex = 0:length(seqs)-1;
            noBits = ceil(log2(maxIndex * shiftFactor + numel(charSrc)));


            for idx = 1:length(seqs)
                currentSeqNum = dec2bin(pairAppend(idx));
                codedArray(idx) = convertCharsToStrings(pad(currentSeqNum, noBits(idx), 'left','0'));
            end

            coded = strjoin(codedArray);
            coded = strrep(coded, ' ', "");

            codedBitsPerSymbol = strlength(coded)/numel(src);
            fixedBitsPerSymbol = log2(shiftFactor);
            compRatio = codedBitsPerSymbol/fixedBitsPerSymbol;

        end

        function dict = getUniqueSeq(~, src)
            % Turns our input string into an array of unique sequences.
            dict = []; % This will keep hold of our unique sequences.
            letterIDX = 1;
            while letterIDX <= length(src)

                seqEndIDX = letterIDX; % Init

                % We use cell2mat because extractBetween returns a cell
                % array instead of a normal char vector.
                seq = cell2mat(extractBetween(src, letterIDX, seqEndIDX));

                while ismember(seq, dict)
                    if(seqEndIDX == length(src))
                        break
                    end
                    seqEndIDX = seqEndIDX + 1;
                    seq = cell2mat(extractBetween(src, letterIDX, seqEndIDX));
                end

                dict = [dict, convertCharsToStrings(seq)];
                letterIDX = seqEndIDX+1;
            end
        end


        function pairs = getIndexSuffixPair(~, seqArr, symbols)
            pairs = zeros(length(seqArr), 2);
            index = zeros(1, length(seqArr));
            suffix = zeros(1, length(seqArr));
            for idx = 1:length(seqArr)

                seq = char(seqArr(idx));
                suffix(idx) = seq(end);

                % We need to find the location of the prefix in our
                % dictionary.

                if strlength(seq) == 1
                    prefix = 0;
                    index(idx) = 0;
                else
                    prefix = convertCharsToStrings(seq(1:end-1));
                    foundAt = find(seqArr == prefix);
                    index(idx) =  foundAt(1);
                end

                % When we try to find a letter in the uniques array
                % we get an integer.
                suffix(idx) = strfind(symbols, suffix(idx))-1;

                pairs(idx, 1) = index(idx);
                pairs(idx, 2) = suffix(idx);
            end

        end

        function text = lempelZivDeco(app, src, charSrc)
            % Enforce case insensitivity.
            %charSrc = unique(lower(charSrc));

            % Calculate maxPairs
            shiftFactor = 2^ceil(log2(numel(charSrc)));
            maxY = numel(charSrc);
            maxX = 0;
            maxBits = [];

            idx = 1;
            maxBitsIdx = 1;

            while idx <= length(src)
                % Calculate the word length
                wordLength = ceil(log2(shiftFactor*maxX + maxY));
                maxBits(maxBitsIdx) = wordLength;
                maxBitsIdx = maxBitsIdx+1;
                idx = idx + wordLength;
                maxX = maxX+1;
            end

            % Now maxBits has the number of bits that represent each
            % index/suffix pair. we now need to group the bits into
            % codewords, then decode the code words into index/suffix
            % pairs.

            codeWords = getCodeWord(app, src, maxBits);
            [index, suffix] = decodeIndexSuffixPairs(app, codeWords, charSrc);

            seqArr = strings(1, numel(codeWords));

            for idx = 1:numel(seqArr)
                if index(idx) == 0
                    seqArr(idx) = charSrc(suffix(idx)+1);
                else
                    seqArr(idx) = strcat(seqArr(index(idx)), charSrc(suffix(idx)+1));
                end
            end

                text = strjoin(seqArr, '\0');
                %text = strrep(text, " ", "");


        end

        function [indexArr, suffixArr] = decodeIndexSuffixPairs(~, codeWords, charSrc)
            % First, find how many bits does the suffix take up
            suffixLen = ceil(log2(numel(charSrc)));

            % Then, split the code words using suffixLen into two parts
            for idx = 1:numel(codeWords)
                codeWord = codeWords(idx);
                splitIndex = strlength(codeWord) - suffixLen;

                if splitIndex == 0
                    indexArr(idx) = "0";
                    suffixArr(idx) = codeWord;
                    continue
                end

                indexArr(idx) = convertCharsToStrings(codeWord{1}(1:splitIndex));
                suffixArr(idx) = convertCharsToStrings(codeWord{1}(splitIndex+1:end));


            end
            indexArr = bin2dec(indexArr);
            suffixArr = bin2dec(suffixArr);
        end

        function codeWords = getCodeWord(~, src, noOfBitsArr)
            codeWords = strings(1, numel(noOfBitsArr));

            idx = 1;
            iterator = 1;
            while idx <= length(src)
                codeWords(iterator) = convertCharsToStrings(cell2mat(extractBetween(src, idx, idx-1+noOfBitsArr(iterator))));
                idx = idx + noOfBitsArr(iterator);
                iterator = iterator+1;
            end

        end

        %adaptive arathmatic function encode
        function encoded_str = Adaptive_Arithmatic_Encode(app,str)


            len = length(str);          % length of the string

            u = unique(str);            % get unique characters from the string

            len_unique = length(u);     % length of the unique characters string


            % General lookup table

            p = zeros(1, len_unique);

            for i = 1 : len_unique          %equalprob
                p(i) = 1 / len_unique;
            end


            % Encoder Table

            low = 0;
            high = 1;
            stage = zeros(1, len_unique + 1);   %table
            stage(1)=low;
            stage(length(stage))=high;

            for i = 2: length(stage)-1

                stage(i)= stage(i-1)+p(i);

            end

            u = sort(u);
            prev_observation = zeros(1, len_unique);
            p_sep = zeros(1, len_unique);

            for i = 1 : len
                for j = 1 : len_unique

                    % if the value from 'str'
                    % matches with 'u' then
                    if str(i) == u(j)
                        prev_observation(j)=prev_observation(j) + 1;

                        for k = 1 : len_unique
                            p_sep(k)=(1+prev_observation(k))/(i+len_unique);
                        end

                        %pos = j;       %for debug

                        low = stage(j);
                        high = stage(j+1);

                        stage(1)=low;
                        stage(length(stage))=high;
                        for ind = 2: length(stage)-1

                            stage(ind)= stage(ind-1)+p_sep(ind-1)*(high - low);


                        end

                        break

                    end

                end

            end


            % displaying tag value

            l_of_bin = ceil(log2(1/(high-low)))+1;

            tag = (low + high)/2;

            encoded_str = '';
            %         for i=1:l_of_bin
            %
            %             tag = tag * 2;
            %
            %             if tag < 1
            %                 encoded_str = [encoded_str '0'];
            %             else
            %                 encoded_str = [encoded_str '1'];
            %                 tag = tag - 1;
            %             end
            %
            %
            %         end
            a = fi((tag),1,l_of_bin,l_of_bin);
            encoded_str = a.bin;


            %encoded_str = tag;
        end

        %adaptive arathmatic function encode
        function str = Adaptive_Arithmatic_Decode(app,str,len,symbol)

            %%decoding
            decoded_str=0;
            encoded_str = convertStringsToChars(string(str));
            tester = encoded_str;
            tester = convertStringsToChars(tester);
            Output=char(num2cell(tester));
            Output=reshape(str2num(Output),1,[]) ;
            decoded_str = Output*2.^(-1:-1:-length(Output)).' ;

            tag = decoded_str;


            u = sort(symbol);
            for i = 1 : length(u)          %equalprob
                p(i) = 1 / length(u);
            end

            low = 0;

            high = 1;


            stage = zeros(1, length(u) + 1);   %table

            stage(1)=low;
            stage(length(stage))=high;

            for i = 2: length(stage)-1
                stage(i)= stage(i-1)+p(i);
            end


            prev_observation = zeros(1, length(u));
            p_sep = zeros(1, length(u));

            str = '';

            for i = 1 : len
                for j = 1 : length(u)

                    % If tag value falls between a certain
                    % range in lookup table then
                    if tag > stage(j) && tag < stage(j+1)
                        prev_observation(j)=prev_observation(j) + 1;
                        %if i ~= 1
                        for k = 1 : length(u)
                            p_sep(k)=(1+prev_observation(k))/(i+length(u));
                        end
                        %end
                        %total_previous_observation_String = total_previous_observation_String + 1;


                        pos = j;
                        display(pos);
                        disp(u(j));

                        low = stage(j);
                        high = stage(j+1);

                        stage(1)=low;
                        stage(length(stage))=high;
                        for ind = 2: length(stage)-1

                            stage(ind)= stage(ind-1)+p_sep(ind-1)*(high - low);
                        end

                        % Geeting the matched tag
                        % value character

                        decoded_str = u(pos);

                        %if decoded_str == ' '
                        str = [str decoded_str];


                        %disp(decoded_str);

                        % String concatenating
                        % 'decoded_str' into 'str'
                        %str = strcat(str, decoded_str);

                        break

                    end

                end


            end

            % Displaying final decoded string
            %disp(str)


        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            src = lower(app.input.Value); % Input source text


            selectedButton = app.SourceSelectButtonGroup_2.SelectedObject;
            if selectedButton.Text == "Custom Charachters"
                app.usedSrcEnco = app.charsField.Value;
            else
                app.usedSrcEnco = app.charList;
            end


            if app.modeSelect.Value == "Adaptive Arithmetic Coding"
                app.OutputLabel.Text = app.Adaptive_Arithmatic_Encode(src);
            else
                [code, compRatio] = app.lempelZivEnco(src, app.usedSrcEnco);
                title = "Copied To Clipboard, Compression Ratio = " + num2str(compRatio,4);
                uialert(app.UIFigure,code, title,"Icon","info" );
                app.OutputLabel.Text = num2str(compRatio*100,4);
                clipboard('copy', code);
            end
        end

        % Button pushed function: RunButtonDeco
        function RunButtonDecoPushed(app, event)
            code = lower(app.inputDeco.Value); % Binary data to decode.

            selectedButton = app.decodeGrp.SelectedObject;
            if selectedButton.Text == "Custom Charachters"
                app.usedSrcDeco = app.charsDeco.Value;
            else
                app.usedSrcDeco = app.charList;
            end

            if app.modeSelectDeco.Value == "Adaptive Arithmetic Coding"
                uialert(app.UIFigure, "ADA ARI", "ADA", "Icon","info");
            else
                text = app.lempelZivDeco(code, app.usedSrcDeco);
                uialert(app.UIFigure, text, "Copied To Clipboard", "Icon", "info");
                clipboard("copy", text);
            end
        end

        % Selection changed function: SourceSelectButtonGroup_2
        function SourceSelectButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.SourceSelectButtonGroup_2.SelectedObject;
            if selectedButton.Text == "Custom Charachters"
                app.charsField.Enable = 'on';
            else
                app.charsField.Enable = 'off';
            end
        end

        % Callback function
        function SourceSelectButtonGroupSelectionChanged(app, event)
            selectedButton = app.SourceSelectButtonGroup.SelectedObject;
            if selectedButton.Text == "Custom Charachters"
                app.charsDeco.Enable = 'on';
            else
                app.charsDeco.Enable = 'off';
            end
        end

        % Selection changed function: decodeGrp
        function decodeGrpSelectionChanged(app, event)
            selectedButton = app.decodeGrp.SelectedObject;
            if selectedButton.Text == "Custom Charachters"
                app.charsDeco.Enable = 'on';
            else
                app.charsDeco.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EncodeTab = uitab(app.TabGroup);
            app.EncodeTab.Title = 'Encode';
            app.InputEditFieldLabel = uilabel(app.EncodeTab);
            app.InputEditFieldLabel.Text = {'Input'; ''};
            app.input = uieditfield(app.EncodeTab, 'text');
            app.input.Value = 'If Peter Piper picked a peck of pickled peppers, where is the peck of pickled peppers Peter Piper picked?';
            app.CompressionAlgorithmDropDownLabel = uilabel(app.EncodeTab);
            app.CompressionAlgorithmDropDownLabel.Text = 'Compression Algorithm';
            app.modeSelect = uidropdown(app.EncodeTab);
            app.modeSelect.Items = {'Adaptive Arithmetic Coding', 'Lempel-Ziv Coding'};
            app.modeSelect.Value = 'Lempel-Ziv Coding';
            app.RunButton = uibutton(app.EncodeTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.OutputLabel = uilabel(app.EncodeTab);
            app.OutputLabel.Text = 'Output:';
            app.SourceSelectButtonGroup_2 = uibuttongroup(app.EncodeTab);
            app.SourceSelectButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @SourceSelectButtonGroup_2SelectionChanged, true);
            app.SourceSelectButtonGroup_2.Title = 'Source Select';
            app.AllCharachtersButton_2 = uiradiobutton(app.SourceSelectButtonGroup_2);
            app.AllCharachtersButton_2.Text = 'All Charachters';
            app.AllCharachtersButton_2.Value = true;
            app.CustomCharachtersButton = uiradiobutton(app.SourceSelectButtonGroup_2);
            app.CustomCharachtersButton.Text = 'Custom Charachters';
            app.EditFieldLabel = uilabel(app.SourceSelectButtonGroup_2);
            app.EditFieldLabel.Enable = 'off';
            app.EditFieldLabel.Text = '';
            app.charsField = uieditfield(app.SourceSelectButtonGroup_2, 'text');
            app.charsField.Enable = 'off';
            app.DecodeTab = uitab(app.TabGroup);
            app.DecodeTab.Title = 'Decode';
            app.InputEditFieldLabel_2 = uilabel(app.DecodeTab);
            app.InputEditFieldLabel_2.Text = {'Input'; ''};
            app.inputDeco = uieditfield(app.DecodeTab, 'text');
            app.inputDeco.Value = '0100000010100111000001111000001000001001110110001011011110001011110111111000100010000000000100000010100101000110011000001000001000110001010000110111000010111000101100010011010101101110111000010000100001000111100111100100000011010000111011000000001110011100100000110100000000100100001110011011100001000100000001000110111100000000011100100110111100000100010010101001000000000001101000001111010111100010111111110001011110011001010011110010010010000000010001001000010000100010010010100011011';
            app.CompressionAlgorithmDropDownLabel_2 = uilabel(app.DecodeTab);
            app.CompressionAlgorithmDropDownLabel_2.Text = 'Compression Algorithm';
            app.modeSelectDeco = uidropdown(app.DecodeTab);
            app.modeSelectDeco.Items = {'Adaptive Arithmetic Coding', 'Lempel-Ziv Coding'};
            app.modeSelectDeco.Value = 'Lempel-Ziv Coding';
            app.RunButtonDeco = uibutton(app.DecodeTab, 'push');
            app.RunButtonDeco.ButtonPushedFcn = createCallbackFcn(app, @RunButtonDecoPushed, true);
            app.RunButtonDeco.Text = 'Run';
            app.decodeGrp = uibuttongroup(app.DecodeTab);
            app.decodeGrp.SelectionChangedFcn = createCallbackFcn(app, @decodeGrpSelectionChanged, true);
            app.decodeGrp.Title = 'Source Select';
            app.AllCharachtersButton_3 = uiradiobutton(app.decodeGrp);
            app.AllCharachtersButton_3.Text = 'All Charachters';
            app.AllCharachtersButton_3.Value = true;
            app.CustomCharachtersButton_2 = uiradiobutton(app.decodeGrp);
            app.CustomCharachtersButton_2.Text = 'Custom Charachters';
            app.EditFieldLabel_2 = uilabel(app.decodeGrp);
            app.EditFieldLabel_2.Enable = 'off';
            app.EditFieldLabel_2.Text = '';
            app.charsDeco = uieditfield(app.decodeGrp, 'text');
            app.charsDeco.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end